classdef Copy_of_finalapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SpeedLimitEditField             matlab.ui.control.NumericEditField
        SpeedLimitLabel                 matlab.ui.control.Label
        TimeBetweenStepsEditField       matlab.ui.control.EditField
        TimeBetweenStepsEditFieldLabel  matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        TurningLimitEditField           matlab.ui.control.NumericEditField
        TurningLimitEditFieldLabel      matlab.ui.control.Label
        PlaceWaypointButton             matlab.ui.control.Button
        DeleteWaypointButton            matlab.ui.control.Button
        WaypointXEditField              matlab.ui.control.NumericEditField
        WaypointXEditField_2Label       matlab.ui.control.Label
        WaypointYEditField              matlab.ui.control.NumericEditField
        WaypointYEditFieldLabel         matlab.ui.control.Label
        StepButton                      matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        TurningAngleGauge               matlab.ui.control.Gauge
        TurningAngleLabel               matlab.ui.control.Label
        ResetWaypointsButton            matlab.ui.control.Button
        SpeedunitssecGauge              matlab.ui.control.Gauge
        SpeedunitssecGaugeLabel         matlab.ui.control.Label
        FinalProjectSelfDrivingCarLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        bot_location
        current_time
        velocity
        omega
        vehicle_mass 
        vehicle_inertia 
        front_stiffness
        rear_stiffness 
        frontToCenter 
        rearToCenter 
        bot_rotation 
        table_index 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.current_time = 0;
            app.velocity = 5;
            app.omega = 0;
            app.vehicle_mass = 1700;
            app.vehicle_inertia = 2000;
            app.front_stiffness = 100000;
            app.rear_stiffness = 80000;
            app.frontToCenter = 1.5;
            app.rearToCenter = 1.3;
            app.bot_rotation = 0;
            app.table_index = 2;

            % initializing initial values
        end

        % Value changed function: TurningLimitEditField
        function TurningLimitEditFieldValueChanged(app, event)
            value = app.TurningLimitEditField.Value;
            app.TurningAngleGauge.ScaleColorLimits = [-150, -1 * value; value, 150];
            % setting parameters
        end

        % Button pushed function: PlaceWaypointButton
        function PlaceWaypointButtonPushed(app, event)
            currentdata = app.UITable.Data;
            waypoint_x = app.WaypointXEditField.Value;
            waypoint_y = app.WaypointYEditField.Value;

            datarow = [waypoint_x waypoint_y];

            app.UITable.Data = [currentdata; datarow];

            %  setting variables equal to GUI components
        end

        % Button pushed function: ResetWaypointsButton
        function ResetWaypointsButtonPushed(app, event)
            app.UITable.Data = [];
            clf(app.UIAxes)

            % having the starting value start at 0
        end

        % Button pushed function: DeleteWaypointButton
        function DeleteWaypointButtonPushed(app, event)
            currentdata = app.UITable.Data;
            newdata = currentdata(1:end-1, :);
            app.UITable.Data = newdata;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            currentdata = app.UITable.Data;
            app.bot_location = [currentdata(1, 1) currentdata(1, 2)];

            % initialize bot location to center and plots it
            plot(app.UIAxes, app.bot_location(1), app.bot_location(2), 'xr');
            hold(app.UIAxes, "on")

            % plots waypoints entered by user
            for i = 1:length(currentdata)
                plot(app.UIAxes, currentdata(i, 1), currentdata(i, 2), 'ko')
            end
            hold(app.UIAxes, "off")
        end

        % Button pushed function: StepButton
        function StepButtonPushed(app, event)
            currentdata = app.UITable.Data;

            % setting the timestep based on user input, establishes amount
            % of time bot will have to move to next waypoint
            timestep =  str2double(app.TimeBetweenStepsEditField.Value);

            currentsteer = app.TurningAngleGauge.Value;
            %read gauge value for desired steering angle
            current_location = app.bot_location;
            waypoint_location = [currentdata(app.table_index, 1) currentdata(app.table_index, 2)];
            distance = sqrt((current_location(1) - waypoint_location(1))^2 + (current_location(2) - waypoint_location(2))^2)
            % calculating distance needed to reach next waypoint
            if distance < app.SpeedLimitEditField.Value
                app.velocity = distance;
            else
                app.velocity = app.SpeedLimitEditField.Value;
            end
            steer_angle = find_steering_angle(app.bot_location, app.bot_rotation, waypoint_location, app.TurningLimitEditField.Value);
             % calculating steering angle needed to reach next waypoint
            if diff([currentsteer steer_angle]) <= 10
                steer_angle = steer_angle;
            else
                steer_angle = currentsteer + steer_angle;
                if steer_angle < currentsteer
                    steer_angle = currentsteer - 10;
                elseif steer_angle > currentsteer
                    steer_angle = currentsteer + 10;
                end
            end
            % correction if else statements to stiffen steering and limit
            % extreme changes
            alpha = acceleration_values(app.velocity, app.omega, app.front_stiffness, app.rear_stiffness, app.frontToCenter, app.rearToCenter, app.vehicle_mass, app.vehicle_inertia, steer_angle)
            % alpha is a double
            app.omega = alpha(1) * timestep;
            % disp(app.omega)
            % omega is a double
            delta_angle = app.omega(1) * timestep;
            % delta angle outputs 3 arguments
            app.bot_rotation = app.bot_rotation + rad2deg(delta_angle);
                
            bot_delta_x = app.velocity * cos(app.bot_rotation);
            bot_delta_y = app.velocity * sin(app.bot_rotation);
            app.bot_location = [app.bot_location(1) + bot_delta_x, app.bot_location(2) + bot_delta_y];
            disp(app.bot_location)
            % converting the change in angle into components so that the
            % "robot" knows where to go next
            app.SpeedunitssecGauge.Value = app.velocity;
            app.TurningAngleGauge.Value = steer_angle;

            hold(app.UIAxes, "on")
            plot(app.UIAxes, current_location(1), current_location(2), app.bot_location(1), app.bot_location(2), '--.r')
            hold(app.UIAxes, "off")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.FinalProjectSelfDrivingCarLabel = uilabel(app.UIFigure);
            app.FinalProjectSelfDrivingCarLabel.Text = 'Final Project: Self Driving Car';
            app.SpeedunitssecGaugeLabel = uilabel(app.UIFigure);
            app.SpeedunitssecGaugeLabel.Text = 'Speed (units/sec)';
            app.SpeedunitssecGauge = uigauge(app.UIFigure, 'circular');
            app.SpeedunitssecGauge.Limits = [0 5];
            app.ResetWaypointsButton = uibutton(app.UIFigure, 'push');
            app.ResetWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetWaypointsButtonPushed, true);
            app.ResetWaypointsButton.Text = 'Reset Waypoints';
            app.TurningAngleLabel = uilabel(app.UIFigure);
            app.TurningAngleLabel.Text = 'Turning Angle (°)';
            app.TurningAngleGauge = uigauge(app.UIFigure, 'circular');
            app.TurningAngleGauge.Limits = [-150 150];
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Waypoint X'; 'Waypoint Y'};
            app.UITable.RowName = {};
            app.StepButton = uibutton(app.UIFigure, 'push');
            app.StepButton.ButtonPushedFcn = createCallbackFcn(app, @StepButtonPushed, true);
            app.StepButton.Text = 'Step';
            app.WaypointYEditFieldLabel = uilabel(app.UIFigure);
            app.WaypointYEditFieldLabel.Text = 'Waypoint Y';
            app.WaypointYEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaypointYEditField.Limits = [-10 10];
            app.WaypointXEditField_2Label = uilabel(app.UIFigure);
            app.WaypointXEditField_2Label.Text = 'Waypoint X';
            app.WaypointXEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaypointXEditField.Limits = [-10 10];
            app.DeleteWaypointButton = uibutton(app.UIFigure, 'push');
            app.DeleteWaypointButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteWaypointButtonPushed, true);
            app.DeleteWaypointButton.Text = 'Delete Waypoint';
            app.PlaceWaypointButton = uibutton(app.UIFigure, 'push');
            app.PlaceWaypointButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceWaypointButtonPushed, true);
            app.PlaceWaypointButton.Text = 'Place Waypoint';
            app.TurningLimitEditFieldLabel = uilabel(app.UIFigure);
            app.TurningLimitEditFieldLabel.Text = 'Turning Limit';
            app.TurningLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.TurningLimitEditField.Limits = [0 150];
            app.TurningLimitEditField.ValueChangedFcn = createCallbackFcn(app, @TurningLimitEditFieldValueChanged, true);
            app.TurningLimitEditField.Value = 45;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TimeBetweenStepsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeBetweenStepsEditFieldLabel.Text = 'Time Between Steps';
            app.TimeBetweenStepsEditField = uieditfield(app.UIFigure, 'text');
            app.TimeBetweenStepsEditField.Value = '0.1';
            app.SpeedLimitLabel = uilabel(app.UIFigure);
            app.SpeedLimitLabel.Text = 'Speed Limit';
            app.SpeedLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedLimitEditField.Limits = [0 150];
            app.SpeedLimitEditField.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_finalapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end