classdef finalapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlotButton                  matlab.ui.control.Button
        TurningLimitEditField       matlab.ui.control.NumericEditField
        TurningLimitEditFieldLabel  matlab.ui.control.Label
        PlaceWaypointButton         matlab.ui.control.Button
        DeleteWaypointButton        matlab.ui.control.Button
        WaypointXEditField          matlab.ui.control.NumericEditField
        WaypointXEditField_2Label   matlab.ui.control.Label
        WaypointYEditField          matlab.ui.control.NumericEditField
        WaypointYEditFieldLabel     matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        TurningAngleGauge           matlab.ui.control.Gauge
        TurningAngleLabel           matlab.ui.control.Label
        ResetWaypointsButton        matlab.ui.control.Button
        SpeedunitssecGauge          matlab.ui.control.Gauge
        SpeedunitssecGaugeLabel     matlab.ui.control.Label
        FinalProjectSelfDrivingCarLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            xVals = [1, 0, -1, 0];
            yVals = [0, 1, 0, -1];
            % scatter(app.UIAxes, xVals, yVals)
        end

        % Value changed function: TurningLimitEditField
        function TurningLimitEditFieldValueChanged(app, event)
            value = app.TurningLimitEditField.Value;
            app.TurningAngleGauge.ScaleColorLimits = [-150, -1 * value; value, 150];
        end

        % Button pushed function: PlaceWaypointButton
        function PlaceWaypointButtonPushed(app, event)
            currentdata = app.UITable.Data;
            waypoint_x = app.WaypointXEditField.Value;
            waypoint_y = app.WaypointYEditField.Value;

            datarow = [waypoint_x waypoint_y];

            app.UITable.Data = [currentdata; datarow];
        end

        % Button pushed function: ResetWaypointsButton
        function ResetWaypointsButtonPushed(app, event)
            app.UITable.Data = [];
        end

        % Button pushed function: DeleteWaypointButton
        function DeleteWaypointButtonPushed(app, event)
            currentdata = app.UITable.Data;
            newdata = currentdata(1:end-1, :);
            app.UITable.Data = newdata;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            currentdata = app.UITable.Data;
            hold(app.UIAxes, "on")
            for i = 1:length(currentdata)
                plot(app.UIAxes, currentdata(i, 1), currentdata(i, 2), 'ko')
            end
            hold(app.UIAxes, "off")
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            currentdata = app.UITable.Data;
            bot_location = [currentdata(1, 1) currentdata(1, 2)];
            i = 2;
            
            velocity = 25;
            omega = 0;
            vehicle_mass = 1700;
            vehicle_inertia = 2000;
            front_stiffness = 100000;
            rear_stiffness = 80000;
            frontToCenter = 1.5;
            rearToCenter = 1.3;

            bot_rotation = 0;

            current_time = clock;

            hold(app.UIAxes, "on")
            while true
                last_time = current_time;
                current_time = clock;

                delta_time = current_time(6) - last_time(6);

                waypoint_location = [currentdata(i, 1) currentdata(i, 2)];
                steer_angle = find_steering_angle(bot_location, bot_rotation, waypoint_location, app.TurningLimitEditField.Value);
                alpha = acceleration_values(velocity, omega, front_stiffness, rear_stiffness, frontToCenter, rearToCenter, vehicle_mass, vehicle_inertia, steer_angle);
                omega = alpha * delta_time;
                delta_angle = omega * delta_time;
                bot_rotation = bot_rotation + rad2deg(delta_angle);
                
                bot_delta_x = velocity * cos(bot_rotation);
                bot_delta_y = velocity * sin(bot_rotation);
                bot_location = [bot_location(1) + bot_delta_x, bot_location(2) + bot_delta_y]

                plot(app.UIAxes, bot_location(1), bot_location(2), 'xr')
            end
            hold(app.UIAxes, "off")

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.FinalProjectSelfDrivingCarLabel = uilabel(app.UIFigure);
            app.FinalProjectSelfDrivingCarLabel.Text = 'Final Project: Self Driving Car';
            app.SpeedunitssecGaugeLabel = uilabel(app.UIFigure);
            app.SpeedunitssecGaugeLabel.Text = 'Speed (units/sec)';
            app.SpeedunitssecGauge = uigauge(app.UIFigure, 'circular');
            app.SpeedunitssecGauge.Limits = [0 5];
            app.ResetWaypointsButton = uibutton(app.UIFigure, 'push');
            app.ResetWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetWaypointsButtonPushed, true);
            app.ResetWaypointsButton.Text = 'Reset Waypoints';
            app.TurningAngleLabel = uilabel(app.UIFigure);
            app.TurningAngleLabel.Text = 'Turning Angle (°)';
            app.TurningAngleGauge = uigauge(app.UIFigure, 'circular');
            app.TurningAngleGauge.Limits = [-150 150];
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Waypoint X'; 'Waypoint Y'};
            app.UITable.RowName = {};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.WaypointYEditFieldLabel = uilabel(app.UIFigure);
            app.WaypointYEditFieldLabel.Text = 'Waypoint Y';
            app.WaypointYEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaypointYEditField.Limits = [-10 10];
            app.WaypointXEditField_2Label = uilabel(app.UIFigure);
            app.WaypointXEditField_2Label.Text = 'Waypoint X';
            app.WaypointXEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaypointXEditField.Limits = [-10 10];
            app.DeleteWaypointButton = uibutton(app.UIFigure, 'push');
            app.DeleteWaypointButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteWaypointButtonPushed, true);
            app.DeleteWaypointButton.Text = 'Delete Waypoint';
            app.PlaceWaypointButton = uibutton(app.UIFigure, 'push');
            app.PlaceWaypointButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceWaypointButtonPushed, true);
            app.PlaceWaypointButton.Text = 'Place Waypoint';
            app.TurningLimitEditFieldLabel = uilabel(app.UIFigure);
            app.TurningLimitEditFieldLabel.Text = 'Turning Limit';
            app.TurningLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.TurningLimitEditField.Limits = [0 150];
            app.TurningLimitEditField.ValueChangedFcn = createCallbackFcn(app, @TurningLimitEditFieldValueChanged, true);
            app.TurningLimitEditField.Value = 45;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end